classdef optimal_thickness < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        AbsorberindexofrefractionEditFieldLabel  matlab.ui.control.Label
        AbsorberindexofrefractionEditField  matlab.ui.control.NumericEditField
        UnitsDropDownLabel        matlab.ui.control.Label
        UnitsDropDown             matlab.ui.control.DropDown
        ThicknessButton           matlab.ui.control.Button
        ThicknessEditField        matlab.ui.control.NumericEditField
        PhaseShiftEditFieldLabel  matlab.ui.control.Label
        PhaseShiftEditField       matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ThicknessButton
        function ThicknessButtonPushed(app, event)
            % calculating optimal thickness from inputs
            n1 = 1; % vacuum
            lambda = 13.5; % nm
            theta1 = 6*pi/180; % 6 degrees in radians
            theta2 = asin(n1/n2*sin(theta1));
            phaseShift = app.PhaseShiftEditField.Value;
            n2 = app.AbsorberindexofrefractionEditField.Value;
            d = phaseShift*lambda/(4*pi*(n1/cos(theta1)-n2/cos(theta2)));
            
            % if d is negative, then we know it's too small and must find
            % the next solution that is positive
            counter = 0;
            while (d<0)
                phaseShift = phaseShift-(2*pi);
                d = phaseShift*lambda/(4*pi*(n1/cos(theta1)-n2/cos(theta2)));
                counter = counter+1;
                if (counter>50) % likely in an infinite loop
                    break
                end
            end
            
            % display optimal thickness
            app.ThicknessEditField.Value = d;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AbsorberindexofrefractionEditFieldLabel = uilabel(app.UIFigure);
            app.AbsorberindexofrefractionEditFieldLabel.Text = 'Absorber index of refraction';
            app.AbsorberindexofrefractionEditField = uieditfield(app.UIFigure, 'numeric');
            app.UnitsDropDownLabel = uilabel(app.UIFigure);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.UIFigure);
            app.UnitsDropDown.Items = {'radians', 'degrees', 'waves', ''};
            app.UnitsDropDown.Value = 'radians';
            app.ThicknessButton = uibutton(app.UIFigure, 'push');
            app.ThicknessButton.ButtonPushedFcn = createCallbackFcn(app, @ThicknessButtonPushed, true);
            app.ThicknessButton.Text = 'Thickness';
            app.ThicknessEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseShiftEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseShiftEditFieldLabel.Text = 'Phase Shift';
            app.PhaseShiftEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optimal_thickness

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end